classdef ImageEnhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectImageLabel             matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        UsingPatch                   matlab.ui.container.Tab
        EditParametersPanel          matlab.ui.container.Panel
        SVDPatchDropDownLabel        matlab.ui.control.Label
        SVDPatchDropDown             matlab.ui.control.DropDown
        ReflectancetauDropDownLabel  matlab.ui.control.Label
        ReflectancetauDropDown       matlab.ui.control.DropDown
        GlobalContrastPanel          matlab.ui.container.Panel
        TypeDropDownLabel            matlab.ui.control.Label
        GTypeDropDown                matlab.ui.control.DropDown
        GlobalImage                  matlab.ui.control.Image
        GammaDropDownLabel           matlab.ui.control.Label
        GammaDropDown                matlab.ui.control.DropDown
        ScalingFactorZDropDownLabel  matlab.ui.control.Label
        ScalingFactorZDropDown       matlab.ui.control.DropDown
        ContrastIntensificationKEditFieldLabel  matlab.ui.control.Label
        FuzzyK                       matlab.ui.control.EditField
        GammaValueLabel              matlab.ui.control.Label
        GammaValueEditField          matlab.ui.control.EditField
        GlobalImage_2                matlab.ui.control.Image
        WeightingParameterEditFieldLabel  matlab.ui.control.Label
        WeightingParameterEditField  matlab.ui.control.EditField
        LocalContrastPanel           matlab.ui.container.Panel
        TypeDropDown_2Label          matlab.ui.control.Label
        LTypeDropDown                matlab.ui.control.DropDown
        ApplyButton                  matlab.ui.control.Button
        ClipLimitDropDownLabel       matlab.ui.control.Label
        ClipLimitDropDown            matlab.ui.control.DropDown
        InputImageLabel              matlab.ui.control.Label
        OutputImageLabel             matlab.ui.control.Label
        ViewErrorScore               matlab.ui.control.Button
    end

    
    properties (Access = public)
        InputImg;
        hueMat;
        satMat;
        valMat;
        OutputImg;
        msgText;
        DialogApp;
        InputHSV;
        reflectImg;
        s1Img;
        origIntensity;
        newIntensity;
        outputHSV;
        processTime;
    end
    
    
    methods (Access = private)
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PERFORMANCE METRICS BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
        
         % C-PCQI metric       
        function[score]= CPCQI(app,img,testImg)
            
            im1=double(rgb2gray(testImg));
            im2=double(rgb2gray(img));

            im3=double(rgb2hsv(testImg));
            im4=double(rgb2hsv(img));
            im3=im3(:,:,3);
            im4=im4(:,:,3);
            
            window = fspecial('gaussian', 11, 1.5);
            window = window/sum(sum(window));
            paddedImg = filter2(window, im1, 'valid'); 
            padSize=21;
              
            [rows,cols]=size(paddedImg);
            paddedTestImg =filter2(window, im2, 'valid');
            summ=0.0;
            numPatch=0;
            
            for i=1: rows
                if(i+padSize -1 > rows)
                    break;
                end
                
                for j=1: cols
                    if(j+padSize -1 > cols)
                        break;
                    end
                    
                    endpt_j=j+padSize-1;
                    endpt_i=i+padSize-1;
                    %get the patch 
                    patchMatrix_Src=paddedImg(i:endpt_i,j:endpt_j);
                    patchMatrix_Test=paddedTestImg(i:endpt_i,j:endpt_j);
                    
                    hsvPatch1=im3(i:endpt_i,j:endpt_j);
                    hsvPatch2=im4(i:endpt_i,j:endpt_j);
                    %calculate the mean, coefficients c1,c2 and vectors v1, v2 
                    ux= mean2(patchMatrix_Src);
                    c1_x=padSize * ux;
                    v2=(patchMatrix_Src-ux) ./  (sqrt(sum(abs(patchMatrix_Src-ux).^2)));
                    c2_x= (sqrt(sum(abs(patchMatrix_Src-ux).^2)));
                    %same for the second image                    
                    uy= mean2(patchMatrix_Test);
                    c1_y=padSize * uy;
                    transTest=patchMatrix_Test.';
                    c2_y=transTest*v2; 
                    c2_y = max(0, c2_y);
                    c2_x = max(0, c2_x);
                    v2 = max(0, v2);
                    
                    C=0.001;
                    % using formula from reference paper to get contrast change
                    QC  =   (4/pi) *atan(abs((c2_y + C )./(c2_x + C )));
                    
                    num1=c2_y + v2*C;
                    denom1= (sqrt(sum(abs(c2_y.* v2 + C).^2)));
                    % using formula from reference paper to get structure distortion change
                    QS=num1 ./ denom1;
                    
                    L=255;
                    % using formula from reference paper to get mean intensity
                    QI=exp(-(abs(c1_x-c1_y)/(padSize * L)));
                    
                    pcqiMaps=QC .* QS .* QI;
                    st1=mean2(hsvPatch1);
                    st2=mean2(hsvPatch2);
                    phi=2;
                    zeta=1;
                    num2=(2*(st1*st2) ) + zeta;
                    denom2=(st1.^2) + (st2.^2) + zeta;
                    % using formula from reference paper to get color saturation
                    QCS= (num2 ./ denom2).^ phi;
                    
                    pcqiMaps= pcqiMaps .* QCS;
                    summ=summ + pcqiMaps;
                    
                    numPatch=numPatch+1;

                end
            end
            finalPCQI= summ ./numPatch;
            score=mean2(finalPCQI);
            
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PERFORMANCE METRICS END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
 
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% LOGARITHMIC MAPPING FUNC BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
 
          function [newIntensityChannel]= logMapping(app,intensityChannel)
            %apply blurring using anisotropic diffusion filter
            filteredChnl= imdiffusefilt(intensityChannel);
            intensityChannel = intensityChannel * 255;
            intensityChannel = uint8(intensityChannel);
            
            [rows,cols]= size(filteredChnl);
            gamma=str2double(get(app.GammaValueEditField, 'Value')); 
            
            for i=1:rows
                for j=1:cols
                    oldVal=double(intensityChannel(i,j));
                    t= filteredChnl(i,j);
                    %apply formula from reference paper for intensity
                    if (t <= 0.5)
                        alphaT= (1- ((t/0.5) ^ gamma)) * 0.5;
                    else
                        alphaT=  (1- (( (1- t)/0.5) ^ gamma)) * (-0.5);
                    end
                    % based on alpha value, get the enhanced value
                    if alphaT > 0
                        num=log((alphaT * oldVal) + 1);
                        denom=log((255*alphaT) + 1);
                        newVal=255* (num/denom);
                    elseif alphaT==0
                        newVal=oldVal;
                    elseif alphaT < 0
                        alphaT=abs(alphaT);
                        num=log((alphaT*(255-oldVal))+1);
                        denom=log((255*alphaT) +1);
                        newVal=255 * (1- (num/denom));
                        
                    end
                    intensityChannel(i,j)= newVal;
                end
            end
            newIntensityChannel = double(intensityChannel);
            newIntensityChannel = newIntensityChannel / 255;
            
          end

 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% LOGARITHMIC MAPPING FUNC END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
 
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% FUZZY LOGIC BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [intensityChannel]= calFuzzyLogic(app, intensityChannel,k)
            intensityChannel = uint8(255 * mat2gray(intensityChannel));
            %get the histogram of the original intensity value
            [counts,binLoc]=imhist(intensityChannel);
            num=0;
            denom=0;
            for i =1:size(counts)
                num= num + (counts(i) * binLoc(i));
                denom=denom +counts(i);
            end
            % get the average intensity from histogram
            sum=num/denom;
            [rows,cols]=size(intensityChannel);
            M=sum;
            %max intensity value
            F=255;
            for i=1: rows
                for j=1:cols
                    oldVal=intensityChannel(i,j);
                    % apply formula to get new value from reference paper
                    if oldVal < M
                        u= (1- (M-oldVal))/ M;
                        newVal= oldVal + (u*k);
                    else
                        u=(F-oldVal) / (F-M);
                        newVal=(oldVal * u) + (F-(u*k));
                    end
                    
                    intensityChannel(i,j)=newVal;
                end
            end
           
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% FUZZY LOGIC END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% AGCWD BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
        function[newIntensityChannel]= getAGCWD(app,intensityChannel,weighting_parameter)
            intensityChannel = intensityChannel * 255;
            intensityChannel = uint8(intensityChannel);

            [counts, binLocs] = imhist(intensityChannel);
            % Compute pdf
            pdf = counts / numel(intensityChannel);
            maxPDF=max(pdf);
            minPDF=min(pdf);
            pdfWeighted = maxPDF*(((pdf-minPDF)/(maxPDF - minPDF)).^weighting_parameter);
            %cdf of new weighted pdf
            cdfWeighted=cumsum(pdfWeighted)/sum(pdfWeighted);
            %create the transformation function
            l = (0:255);
            l_max = 255;% maximum
            % using formula to get enhanced value
            for i=1:256
                l(i)=l_max * (l(i)/l_max)^(1-cdfWeighted(i));
            end
            l=uint8(l);
            enhanced_image = intensityChannel;

            %apply the new transformation to the original intensity channel
            [height, width] = size(intensityChannel);
            for i = 1:height
                for j = 1:width
                    value = enhanced_image(i,j);
                    enhanced_image(i,j) = l(value+1);
                end
            end
            newIntensityChannel = double(enhanced_image);
            newIntensityChannel = newIntensityChannel / 255;
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% AGCWD END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% USING SATURATION CHANNEL BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
        function [enhancedChnl]= processSaturationChnl(app)
            %apply discrete wavelet transform
            % cA=approximation coefficients matrix
            % cH, cV, cD= coeffiecints matries of horizontal,vertical and diagonal
            [cA,cH,cV,cD]=dwt2(app.satMat,'haar');
            oldMaxVal=max(cA(:));
            oldMinVal=min(cA(:));
            % use upscaling triangle transform function to get min and max as per reference paper
            newMinVal=oldMinVal * 2.5289;
            newMaxVal=oldMaxVal * 0.9; 
            
            [row,col]=size(cA);
            newCA=zeros(row,col);
            % apply upscaling triangle transform function to each pixel as per reference paper
            for i=1:row
                for j=1:col
                    denom=oldMaxVal-oldMinVal;
                    sub1=newMaxVal-cA(i,j);
                    sub2=newMaxVal-newMinVal;
                    num=sub1*sub2;
                    newValue=newMaxVal - (num/denom);
                    newCA(i,j)=newValue;
                end
            end
            %apply inverse wavelet transform 
            enhancedChnl=idwt2(newCA,cH,cV,cD,'haar',size(app.satMat));
        end
 
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% USING SATURATION CHANNEL END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
  
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% BBHE BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
 
        function [newIntensityChannel]= getBBHE(app,intensityChannel)
            intensityChannel = intensityChannel * 255;
            intensityChannel = uint8(intensityChannel);
            % calculate the mean of the intensity channel
            histgramMean = round(mean2(intensityChannel));
            % get the lower and higher intensity than the mean
            IntensityLow = find(intensityChannel>=histgramMean);
            IntensityHigh = find(intensityChannel<histgramMean);
            
            valueChannelLow = intensityChannel;
            valueChannelLow(IntensityLow)=0;
            valueChannelHigh = intensityChannel;
            valueChannelHigh(IntensityHigh)=0;
            % histogram equalization
            [counts1, binLocsL] = histeq(valueChannelLow,histgramMean);
            [counts2, binLocsH] = histeq(valueChannelHigh,256-histgramMean);
            %create lookup table and get new value based on the lookup table
            lookupTable = uint8([histgramMean*binLocsL(1:histgramMean), 256*binLocsH(histgramMean+1:end)]);
            newVal = intlut(intensityChannel, lookupTable);
            % get the enhanced intensity
            newIntensityChannel = double(newVal);
            newIntensityChannel = newIntensityChannel / 255;
    
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% BBHE END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% GAMMA CORRECTION BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
        function gammaResult=gammaCorrection(app,S1)
            % get the scaling factor and gamma parameter
            Z = str2double(app.ScalingFactorZDropDown.Value);
            Gamma =str2double(app.GammaDropDown.Value);
            powVal= 1.0/ Gamma;
            val1=(S1/Z)^powVal;
            % use the defined formula from reference paper
            gammaResult= (255 * val1)/100;
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% GAMMA CORRECTION END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
 
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% GET PATCH Func BEGIN %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [reflectA,illuminationA,illuminationGamma,enhancedIntensity]= getPatch(app)
            valueChannel=app.valMat;
            v=app.SVDPatchDropDown.Value;
            [r,c]=size(valueChannel);
            % initialize the reflection and illumination component matrix
            reflectA=zeros(r,c);
            reflectA=double(reflectA);
            
            illuminationA=zeros(r,c);
            illuminationA=double(illuminationA);
            
            illuminationGamma=zeros(r,c);
            illuminationGamma=double(illuminationGamma);
            
            enhancedIntensity=zeros(r,c);
            enhancedIntensity=double(enhancedIntensity);
            
            tau=app.ReflectancetauDropDown.Value;
            tau=str2double(tau);
            
            %padding image matrix with zero based on patch size
            if v=="3 x 3"
                paddedImg = padarray(valueChannel,[1 1],0,'both');
                padSize=3;
                jump=1;
            elseif v=="5 x 5"
                paddedImg = padarray(valueChannel,[2 2],0,'both');
                padSize=5;
                jump=2;
            elseif v=="7 x 7"
                paddedImg = padarray(valueChannel,[3 3],0,'both');
                padSize=7;
                jump=3;
            elseif v=="9 x 9"
                paddedImg = padarray(valueChannel,[4 4],0,'both');
                padSize=9;
                jump=4;
            elseif v=="11 x 11"
                paddedImg = padarray(valueChannel,[5 5],0,'both');
                padSize=11;
                jump=5;
            end
            [rows,cols]=size(paddedImg);
            %slide window and get the image patch
            for i=1: rows
                if(i+padSize -1 > rows)
                    break;
                end
                
                for j=1: cols
                    if(j+padSize -1 > cols)
                        break;
                    end
                    
                    endpt_j=j+padSize-1;
                    endpt_i=i+padSize-1;
                    patchMatrix=paddedImg(i:endpt_i,j:endpt_j);
                    % calculate svd of the matrix
                    [U, S, V]= svd(patchMatrix);
                    % get the principal energy i.e s1
                    s1=S(1);
                    s1=double(s1);
                    
                    % get Reflectance component
                    f_x_y= paddedImg(i+jump,j+jump);
                    denom=s1 + tau;
                    reflectResult=f_x_y/denom ;
                    reflectA(i,j)= reflectResult;
                    
                    % get Illumination component
                    illuminationA(i,j)=s1;
                     if strcmp(app.GTypeDropDown.Value,'Gamma Correction')
                        %Gamma Correction Illumination
                        gammaResult = gammaCorrection(app,s1);
                        illuminationGamma(i,j)=gammaResult;
                        enhancedIntensity(i,j)= gammaResult * reflectResult;
                     else
                         enhancedIntensity(i,j)= s1 * reflectResult;
                     end
                end
            end
            %apply the global contrast algorithm 
            if strcmp(app.GTypeDropDown.Value,'Fuzzy Logic')
                k=str2double(app.FuzzyK.Value);
                enhancedIntensity=calFuzzyLogic(app,enhancedIntensity,k);
            elseif strcmp(app.GTypeDropDown.Value,'Logarithmic Mapping')
                enhancedIntensity=logMapping(app,enhancedIntensity);
            elseif strcmp(app.GTypeDropDown.Value,'AGCWD')
                weightingParam=str2double(app.WeightingParameterEditField.Value);
                enhancedIntensity=getAGCWD(app,enhancedIntensity,weightingParam);
            elseif strcmp(app.GTypeDropDown.Value,'BBHE')
                enhancedIntensity = getBBHE(app,enhancedIntensity);
            end
            
        end
 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% GET PATCH Func END %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
  
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                drawnow;
                app.UIFigure.WindowState = 'maximized';
                app.UIFigure.Name = 'Image Enhancement';
                app.UsingPatch.Title='Using Patch';
                app.GlobalImage.ImageSource='GammaPic.jpg';
                set(app.ContrastIntensificationKEditFieldLabel,'visible','off');
                set(app.FuzzyK,'visible','off');
                set(app.GammaValueEditField,'visible','off');
                set(app.GammaValueLabel,'visible','off');
                set(app.GlobalImage,'visible','on');
                set(app.GlobalImage_2,'visible','off');
                set(app.WeightingParameterEditField,'visible','off');
                set(app.WeightingParameterEditFieldLabel,'visible','off');
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [filename, pathname] = uigetfile('*.jpg', 'Select file'); 
             if isequal(filename,0)
                 msgbox('Please select file','File Selection','warn');
             else
                 %select the image file
                 completeName=strcat(pathname, filename);
                 set(app.Label,'Text',completeName);
                 inputImage=imread(completeName);
                 %convert to HSV
                 hsv=rgb2hsv(inputImage);
                 app.InputImg=inputImage;
                 app.hueMat = hsv(:, :, 1); % Hue channel
                 app.satMat = hsv(:, :, 2); % Saturation channel
                 app.valMat = hsv(:, :, 3); % Value (intensity) channel
                 app.InputHSV=hsv;
                 app.origIntensity=app.valMat;
                 imshow(inputImage,'Parent',app.UIAxes);
                 drawnow;
                 figure(app.UIFigure);
             end
             drawnow;
             figure(app.UIFigure);
        end

        % Value changed function: GTypeDropDown
        function GTypeDropDownValueChanged(app, event)
            value = app.GTypeDropDown.Value;
            
            if(value=="Gamma Correction")
                A=imread('GammaPic.jpg');
                app.GlobalImage.ImageSource='GammaPic.jpg';
                set(app.GammaDropDown,'visible','on');
                set(app.GammaDropDownLabel,'visible','on');
                set(app.ScalingFactorZDropDown,'visible','on');
                set(app.ScalingFactorZDropDownLabel,'visible','on');
                set(app.ContrastIntensificationKEditFieldLabel,'visible','off');
                set(app.FuzzyK,'visible','off');
                set(app.GammaValueEditField,'visible','off');
                set(app.GammaValueLabel,'visible','off');
                set(app.GlobalImage,'visible','on');
                set(app.GlobalImage_2,'visible','off');
                set(app.WeightingParameterEditField,'visible','off');
                set(app.WeightingParameterEditFieldLabel,'visible','off');
            elseif (value=="Fuzzy Logic")
                set(app.GammaDropDown,'visible','off');
                set(app.GammaDropDownLabel,'visible','off');
                set(app.ScalingFactorZDropDown,'visible','off');
                set(app.ScalingFactorZDropDownLabel,'visible','off');
                set(app.ContrastIntensificationKEditFieldLabel,'visible','on');
                set(app.FuzzyK,'visible','on');
                set(app.GammaValueEditField,'visible','off');
                set(app.GammaValueLabel,'visible','off');
                app.GlobalImage.ImageSource='FuzzyLogicPic.jpg';
                set(app.GlobalImage,'visible','on');
                set(app.GlobalImage_2,'visible','off');
                set(app.WeightingParameterEditField,'visible','off');
                set(app.WeightingParameterEditFieldLabel,'visible','off');
            elseif (value=="Logarithmic Mapping")
                set(app.GammaDropDown,'visible','off');
                set(app.GammaDropDownLabel,'visible','off');
                set(app.ScalingFactorZDropDown,'visible','off');
                set(app.ScalingFactorZDropDownLabel,'visible','off');
                set(app.ContrastIntensificationKEditFieldLabel,'visible','off');
                set(app.FuzzyK,'visible','off');
                set(app.GammaValueEditField,'visible','on');
                set(app.GammaValueLabel,'visible','on');
                app.GlobalImage.ImageSource='LogPic.jpg';
                set(app.GlobalImage,'visible','on');
                set(app.GlobalImage_2,'visible','off');
                set(app.WeightingParameterEditField,'visible','off');
                set(app.WeightingParameterEditFieldLabel,'visible','off');
            elseif (value=="AGCWD")
                set(app.GammaDropDown,'visible','off');
                set(app.GammaDropDownLabel,'visible','off');
                set(app.ScalingFactorZDropDown,'visible','off');
                set(app.ScalingFactorZDropDownLabel,'visible','off');
                set(app.ContrastIntensificationKEditFieldLabel,'visible','off');
                set(app.FuzzyK,'visible','off');
                set(app.GammaValueEditField,'visible','off');
                set(app.GammaValueLabel,'visible','off');
                app.GlobalImage.ImageSource='AGCWDPic.jpg';
                set(app.GlobalImage,'visible','on');
                set(app.GlobalImage_2,'visible','off');
                set(app.WeightingParameterEditField,'visible','on');
                set(app.WeightingParameterEditFieldLabel,'visible','on');
            elseif (value=="Saturation")
                set(app.GammaDropDown,'visible','off');
                set(app.GammaDropDownLabel,'visible','off');
                set(app.ScalingFactorZDropDown,'visible','off');
                set(app.ScalingFactorZDropDownLabel,'visible','off');
                set(app.ContrastIntensificationKEditFieldLabel,'visible','off');
                set(app.FuzzyK,'visible','off');
                set(app.GammaValueEditField,'visible','off');
                set(app.GammaValueLabel,'visible','off');
                app.GlobalImage_2.ImageSource='SaturationPic.jpg';
                set(app.GlobalImage,'visible','off');
                set(app.GlobalImage_2,'visible','on');
                set(app.WeightingParameterEditField,'visible','off');
                set(app.WeightingParameterEditFieldLabel,'visible','off');
            elseif (value=="<--Select-->" || value=="BBHE")
                set(app.GammaDropDown,'visible','off');
                set(app.GammaDropDownLabel,'visible','off');
                set(app.ScalingFactorZDropDown,'visible','off');
                set(app.ScalingFactorZDropDownLabel,'visible','off');
                set(app.ContrastIntensificationKEditFieldLabel,'visible','off');
                set(app.FuzzyK,'visible','off');
                set(app.GammaValueEditField,'visible','off');
                set(app.GammaValueLabel,'visible','off');
                set(app.GlobalImage,'visible','off');
                set(app.GlobalImage_2,'visible','off');
                set(app.WeightingParameterEditField,'visible','off');
                set(app.WeightingParameterEditFieldLabel,'visible','off');
            end
        end

        % Callback function
        function SaveOutputButtonPushed(app, event)
            m= randomUnitVector(app,3);
            uialert(app.UIFigure,m,'Invalid File');
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            tic;% to start the timer
            if strcmp(app.GTypeDropDown.Value,'Fuzzy Logic')
                val=str2double(get(app.FuzzyK, 'Value'));
                % check the valid range of K for fuzzy logic
                if ~(val>0 && val<256)
                    errordlg('K should be in range [0,255]','Invalid Parameter Value');
                    return;
                end
            end
            % process the image patch based
            app.OutputImageLabel.Text='Processing Output Image';
            drawnow;
            [reflectanceImage,illuminationImage,illuminationGamma,enhancedIntensity]= getPatch(app);
            if strcmp(app.GTypeDropDown.Value,'Fuzzy Logic')
                enhancedIntensity = mat2gray(enhancedIntensity);    
            end
            app.reflectImg=reflectanceImage;
            app.s1Img=illuminationImage;
            
            A=enhancedIntensity;
            %apply local contrast algorithm
            if app.LTypeDropDown.Value=="CLAHE"
                climitVal=str2double(app.ClipLimitDropDown.Value);
                A = adapthisteq(enhancedIntensity,'clipLimit',climitVal,'Distribution','uniform');
            elseif app.LTypeDropDown.Value=="AHE"
                A = adapthisteq(enhancedIntensity,'clipLimit',0,'NumTiles',[64,64]);
            end
            % modify saturation channel and concatenate the hsv channels to
            % form output image
            if strcmp(app.GTypeDropDown.Value,'Saturation')
               S= processSaturationChnl(app);
               HSVImg = cat(3, app.hueMat, S, A);
            else
               HSVImg = cat(3, app.hueMat, app.satMat, A); 
            end
            % convert back to RGB image                        
            RGBOutput = hsv2rgb(HSVImg);
            app.newIntensity=A;
            app.outputHSV=HSVImg;
            
            app.InputImg= im2double(app.InputImg);
            app.OutputImg=RGBOutput;
            imshow(RGBOutput,'Parent',app.UIAxes_2);
            app.OutputImageLabel.Text='Output Image';
            drawnow;
            app.processTime=toc;% get the process time
        end

        % Callback function
        function ApplyButton_2Pushed(app, event)
            getGradient(app);
        end

        % Button pushed function: ViewErrorScore
        function ViewErrorScoreButtonPushed(app, event)
            % perform evaluation using C-PCQI,MSE, PSNR metric
            cpcqiErr=CPCQI(app,app.InputImg,app.OutputImg);
            mseErr = immse(app.InputImg, app.OutputImg);
            peaksnr = psnr(app.InputImg, app.OutputImg);
            psnrValue=sprintf('%.4f', peaksnr);
            mseValue=sprintf('%.4f', mseErr);
            cpcqiValue=sprintf('%.4f', cpcqiErr);
            pTime=sprintf('%.4f', app.processTime);
            %open the dialog box
            app.DialogApp=childApp(app,app.InputHSV,app.reflectImg,app.s1Img,app.origIntensity,app.newIntensity,app.outputHSV,mseValue,psnrValue,cpcqiValue,pTime);

        end

        % Value changed function: LTypeDropDown
        function LTypeDropDownValueChanged(app, event)
            value = app.LTypeDropDown.Value;
            if value=="CLAHE"
                set(app.ClipLimitDropDown,'visible','on');
                set(app.ClipLimitDropDownLabel,'visible','on');
            elseif value=="AHE"
                set(app.ClipLimitDropDown,'visible','off');
                set(app.ClipLimitDropDownLabel,'visible','off');
            elseif value=="BBHE"
                set(app.ClipLimitDropDown,'visible','off');
                set(app.ClipLimitDropDownLabel,'visible','off');
            elseif value=="<--Select-->"  
                set(app.ClipLimitDropDown,'visible','off');
                set(app.ClipLimitDropDownLabel,'visible','off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectImageLabel = uilabel(app.UIFigure);
            app.SelectImageLabel.Text = 'Select Image:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UsingPatch = uitab(app.TabGroup);
            app.UsingPatch.Title = 'Tab';
            app.EditParametersPanel = uipanel(app.UsingPatch);
            app.EditParametersPanel.Title = 'Edit Parameters:';
            app.SVDPatchDropDownLabel = uilabel(app.EditParametersPanel);
            app.SVDPatchDropDownLabel.Text = 'SVD Patch:';
            app.SVDPatchDropDown = uidropdown(app.EditParametersPanel);
            app.SVDPatchDropDown.Items = {'3 x 3', '5 x 5', '7 x 7', '9 x 9', '11 x 11'};
            app.SVDPatchDropDown.Value = '3 x 3';
            app.ReflectancetauDropDownLabel = uilabel(app.EditParametersPanel);
            app.ReflectancetauDropDownLabel.Text = 'Reflectance (tau):';
            app.ReflectancetauDropDown = uidropdown(app.EditParametersPanel);
            app.ReflectancetauDropDown.Items = {'0.1', '0.2', '0.3', '0.4', '0.5', '0.6', '0.7', '0.8', '0.9', '1'};
            app.ReflectancetauDropDown.Value = '0.1';
            app.GlobalContrastPanel = uipanel(app.EditParametersPanel);
            app.GlobalContrastPanel.Title = 'Global Contrast:';
            app.TypeDropDownLabel = uilabel(app.GlobalContrastPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.GTypeDropDown = uidropdown(app.GlobalContrastPanel);
            app.GTypeDropDown.Items = {'<--Select-->', 'Gamma Correction', 'Fuzzy Logic', 'AGCWD', 'Logarithmic Mapping', 'Saturation', 'BBHE'};
            app.GTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GTypeDropDownValueChanged, true);
            app.GTypeDropDown.Value = 'Gamma Correction';
            app.GlobalImage = uiimage(app.GlobalContrastPanel);
            app.GammaDropDownLabel = uilabel(app.GlobalContrastPanel);
            app.GammaDropDownLabel.Text = 'Gamma:';
            app.GammaDropDown = uidropdown(app.GlobalContrastPanel);
            app.GammaDropDown.Items = {'1.0', '1.5', '2.0', '2.2', '2.5', '3', '5'};
            app.GammaDropDown.Value = '2.2';
            app.ScalingFactorZDropDownLabel = uilabel(app.GlobalContrastPanel);
            app.ScalingFactorZDropDownLabel.Text = 'Scaling Factor (Z):';
            app.ScalingFactorZDropDown = uidropdown(app.GlobalContrastPanel);
            app.ScalingFactorZDropDown.Items = {'1.0', '2.0', '3.0', '4.0', '5.0', '6.0', '7.0', '8.0', '9.0', '10.0'};
            app.ScalingFactorZDropDown.Value = '5.0';
            app.ContrastIntensificationKEditFieldLabel = uilabel(app.GlobalContrastPanel);
            app.ContrastIntensificationKEditFieldLabel.Text = 'Contrast Intensification (K):';
            app.FuzzyK = uieditfield(app.GlobalContrastPanel, 'text');
            app.FuzzyK.Value = '180';
            app.GammaValueLabel = uilabel(app.GlobalContrastPanel);
            app.GammaValueLabel.Text = 'Gamma Value:';
            app.GammaValueEditField = uieditfield(app.GlobalContrastPanel, 'text');
            app.GammaValueEditField.Value = '0.05';
            app.GlobalImage_2 = uiimage(app.GlobalContrastPanel);
            app.WeightingParameterEditFieldLabel = uilabel(app.GlobalContrastPanel);
            app.WeightingParameterEditFieldLabel.Text = 'Weighting Parameter';
            app.WeightingParameterEditField = uieditfield(app.GlobalContrastPanel, 'text');
            app.WeightingParameterEditField.Value = '0.75';
            app.LocalContrastPanel = uipanel(app.EditParametersPanel);
            app.LocalContrastPanel.Title = 'Local Contrast:';
            app.TypeDropDown_2Label = uilabel(app.LocalContrastPanel);
            app.TypeDropDown_2Label.Text = 'Type:';
            app.LTypeDropDown = uidropdown(app.LocalContrastPanel);
            app.LTypeDropDown.Items = {'<--Select-->', 'CLAHE', 'AHE'};
            app.LTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LTypeDropDownValueChanged, true);
            app.LTypeDropDown.Value = 'CLAHE';
            app.ApplyButton = uibutton(app.LocalContrastPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ClipLimitDropDownLabel = uilabel(app.LocalContrastPanel);
            app.ClipLimitDropDownLabel.Text = 'Clip Limit:';
            app.ClipLimitDropDown = uidropdown(app.LocalContrastPanel);
            app.ClipLimitDropDown.Items = {'0.01', '0.02', '0.05', '0.08', '0.1', '0.5', '1'};
            app.ClipLimitDropDown.Value = '0.01';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Text = 'Output Image';
            app.ViewErrorScore = uibutton(app.UIFigure, 'push');
            app.ViewErrorScore.ButtonPushedFcn = createCallbackFcn(app, @ViewErrorScoreButtonPushed, true);
            app.ViewErrorScore.Text = 'View Performance Score';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageEnhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end