classdef childApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OriginalHSVImageLabel      matlab.ui.control.Label
        ReflectanceComponentLabel  matlab.ui.control.Label
        UIAxes_1                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
        UIAxes_6                   matlab.ui.control.UIAxes
        PrincipalEnergys1Label     matlab.ui.control.Label
        OriginalIntensityVLabel    matlab.ui.control.Label
        EnhancedIntensityLabel     matlab.ui.control.Label
        OutputHSVImageLabel        matlab.ui.control.Label
        PerformanceMetrics         matlab.ui.container.Panel
        MSELabel                   matlab.ui.control.Label
        MSEValue                   matlab.ui.control.Label
        PSNRLabel                  matlab.ui.control.Label
        PSNRValue                  matlab.ui.control.Label
        C_PCQILabel                matlab.ui.control.Label
        C_PCQIValue                matlab.ui.control.Label
        ProcessTimeLabel           matlab.ui.control.Label
        ProcessTimeValue           matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ImageEnhancement, inputHSV, reflectImg, s1Img, origIntensity, enhancedIntensity, outputHSV, MSEScore, PSNRScore, CPCQIScore, ProcessTime)
            app.callingApp=ImageEnhancement;
            %assigning parents paramaeter values to
            imshow(inputHSV,'Parent',app.UIAxes_1);
            imshow(reflectImg,'Parent',app.UIAxes_2);
            imshow(s1Img,'Parent',app.UIAxes_3);
            imshow(origIntensity,'Parent',app.UIAxes_4);
            imshow(enhancedIntensity,'Parent',app.UIAxes_5);
            imshow(outputHSV,'Parent',app.UIAxes_6);
            % assign metric performance scores
            app.PSNRValue.Text=PSNRScore;
            app.MSEValue.Text=MSEScore;
            app.C_PCQIValue.Text=CPCQIScore;
            app.ProcessTimeValue.Text=ProcessTime;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.Name = 'Detail Views';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OriginalHSVImageLabel = uilabel(app.UIFigure);
            app.OriginalHSVImageLabel.Text = 'Original HSV Image';
            app.ReflectanceComponentLabel = uilabel(app.UIFigure);
            app.ReflectanceComponentLabel.Text = 'Reflectance Component';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.PrincipalEnergys1Label = uilabel(app.UIFigure);
            app.PrincipalEnergys1Label.Text = 'Principal Energy (s1)';
            app.OriginalIntensityVLabel = uilabel(app.UIFigure);
            app.OriginalIntensityVLabel.Text = 'Original Intensity (V)';
            app.EnhancedIntensityLabel = uilabel(app.UIFigure);
            app.EnhancedIntensityLabel.Text = 'Enhanced Intensity';
            app.OutputHSVImageLabel = uilabel(app.UIFigure);
            app.OutputHSVImageLabel.Text = 'Output HSV Image';
            app.PerformanceMetrics = uipanel(app.UIFigure);
            app.PerformanceMetrics.Title = 'Performance Metrics';
            app.MSELabel = uilabel(app.PerformanceMetrics);
            app.MSELabel.Text = 'MSE:';
            app.MSEValue = uilabel(app.PerformanceMetrics);
            app.MSEValue.Text = 'val';
            app.PSNRLabel = uilabel(app.PerformanceMetrics);
            app.PSNRLabel.Text = 'PSNR:';
            app.PSNRValue = uilabel(app.PerformanceMetrics);
            app.PSNRValue.Text = 'val';
            app.C_PCQILabel = uilabel(app.PerformanceMetrics);
            app.C_PCQILabel.Text = 'C-PCQI:';
            app.C_PCQIValue = uilabel(app.PerformanceMetrics);
            app.C_PCQIValue.Text = 'val';
            app.ProcessTimeLabel = uilabel(app.PerformanceMetrics);
            app.ProcessTimeLabel.Text = 'Process Time:';
            app.ProcessTimeValue = uilabel(app.PerformanceMetrics);
            app.ProcessTimeValue.Text = 'val';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = childApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end